classdef vist < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        VISTUIFigure                 matlab.ui.Figure
        GridLayout                   matlab.ui.container.GridLayout
        OptionsPanel                 matlab.ui.container.Panel
        GridLayout7                  matlab.ui.container.GridLayout
        GridLayout8                  matlab.ui.container.GridLayout
        LaunchFlightGearButton       matlab.ui.control.Button
        GridLayout9                  matlab.ui.container.GridLayout
        ConnectionLampLabel          matlab.ui.control.Label
        ConnectionLamp               matlab.ui.control.Lamp
        GridLayout10                 matlab.ui.container.GridLayout
        VehicleDropDownLabel         matlab.ui.control.Label
        VehicleDropDown              matlab.ui.control.DropDown
        ViewDropDownLabel            matlab.ui.control.Label
        ViewDropDown                 matlab.ui.control.DropDown
        WeatherDropDownLabel         matlab.ui.control.Label
        WeatherDropDown              matlab.ui.control.DropDown
        GridLayout13                 matlab.ui.container.GridLayout
        EarthViewCheckBox            matlab.ui.control.CheckBox
        TerraSyncCheckBox            matlab.ui.control.CheckBox
        StartTimeUTCDatePickerLabel  matlab.ui.control.Label
        StartTimeUTCDatePicker       matlab.ui.control.DatePicker
        TimeOfDayDropDown            matlab.ui.control.DropDown
        Label                        matlab.ui.control.Label
        GridLayout11                 matlab.ui.container.GridLayout
        RunCommandEditFieldLabel     matlab.ui.control.Label
        RunCommandEditField          matlab.ui.control.EditField
        GridLayout12                 matlab.ui.container.GridLayout
        PlaybackSpeedSliderLabel     matlab.ui.control.Label
        PlaybackSpeedSlider          matlab.ui.control.Slider
        TargetFPSSliderLabel         matlab.ui.control.Label
        TargetFPSSlider              matlab.ui.control.Slider
        SimulationPanel              matlab.ui.container.Panel
        GridLayout2                  matlab.ui.container.GridLayout
        UITable                      matlab.ui.control.Table
        GridLayout4                  matlab.ui.container.GridLayout
        LoadCaseButton               matlab.ui.control.Button
        PlayButton                   matlab.ui.control.Button
        GridLayout5                  matlab.ui.container.GridLayout
        AngleUnitsDropDownLabel      matlab.ui.control.Label
        AngleUnitsDropDown           matlab.ui.control.DropDown
        GridLayout6                  matlab.ui.container.GridLayout
        DistanceUnitsDropDownLabel   matlab.ui.control.Label
        DistanceUnitsDropDown        matlab.ui.control.DropDown
        ContextMenu                  matlab.ui.container.ContextMenu
        AddRowMenu                   matlab.ui.container.Menu
        ClearDataMenu                matlab.ui.container.Menu
        ContextMenu2                 matlab.ui.container.ContextMenu
        EditMenu                     matlab.ui.container.Menu
    end

    properties (Access = private)
		T; % Telnet-Python interface
		polling; % internal timer to check status every few seconds
        animation; % FlightGearAnimation object handle
        isconnected; % status of Telnet connection
		vehicle; % name of 'custom' aircraft
        directory; % path to a vehicle's <custom>-set.xml file
    end
    
    properties (Access = private, Constant)
		opts = ' --units-meters --enable-freeze'; % basic startup command line options for FlightGear
		dataport = uint64(5501); % for Telnet connection
		playport = uint64(5502); % for MATLAB's 'network' FDM
		wait = 5; % [s]
		anglcol = [2:3,5:7]; % angular data columns
		distcol = 4; % altitude column
    end
    
    methods (Access = private)
        function checkStatus(app)
			try
				freeze = app.T.get('/sim/freeze/master');
				if any(strcmp(char(freeze), {'true', 'false'}))
					app.isconnected = true;
					app.ConnectionLampLabel.Text = 'Connected';
					app.ConnectionLamp.Color = [0 1 0];
				else
					app.isconnected = false;
					app.ConnectionLampLabel.Text = 'Disconnected';
					app.ConnectionLamp.Color = [1 0 0];
				end
			catch
				% Something broke, stop timer...
				app.polling.stop();
				
				% ... and update lamp
				app.isconnected = false;
				app.ConnectionLampLabel.Text = 'Disconnected';
				app.ConnectionLamp.Color = [1 0 0];
			end
        end
        
        function togglePlayStatus(app)
            app.PlayButton.Enable = ~app.PlayButton.Enable;
        end
        
        function opts = getOpts(app)
			% Always present
            opts = [app.opts, ...
				' --props=' num2str(app.dataport), ...
				' --fdm=network,localhost,' num2str(app.playport), ...
				' --aircraft=' app.vehicle, ...
				' --start-date-gmt=' [datestr(app.StartTimeUTCDatePicker.Value, 'yyyy:mm:dd') ':' app.TimeOfDayDropDown.Value], ...
				' --prop:/sim/current-view/view-number=' app.ViewDropDown.Value];
			
			% Append custom 'aircraft' directory
			if strcmp(app.VehicleDropDown.Value, 'custom')
				opts = [opts ' --aircraft-dir=' app.directory];
			end
			
			% Start at initial position if is known
			if ~isempty(app.UITable.Data)
				data0 = app.UITable.Data(1,:);
				data0(app.anglcol) = data0(app.anglcol) * app.AngleUnitsDropDown.Value;
				data0(app.distcol) = data0(app.distcol) * app.DistanceUnitsDropDown.Value;
				opts = [opts ' --lat=' num2str(data0(2)) ' --lon=' num2str(data0(3)) ' --altitude=' num2str(data0(4))];
			end
			
			% TerraSync
			if app.TerraSyncCheckBox.Value
				opts = [opts ' --enable-terrasync'];
			end
			
			% EarthView
			if app.EarthViewCheckBox.Value
				opts = [opts, ...
					' --prop:/earthview/control_loop_flag=1', ...
					' --prop:/earthview/show-force-all=1', ...
					' --prop:/earthview/show-n1=true', ...
					' --prop:/earthview/show-n2=true', ...
					' --prop:/earthview/show-n3=true', ...
					' --prop:/earthview/show-n4=true', ...
					' --prop:/earthview/show-s1=true', ...
					' --prop:/earthview/show-s2=true', ...
					' --prop:/earthview/show-s3=true', ...
					' --prop:/earthview/show-s4=true'];
			end
        end
    end
	
	methods (Access = private, Static)
		function newcontent = xmlrep(oldcontent, key, val)
			newcontent = regexprep(oldcontent, ['(?<=<' key '>)(.*?)(?=<\/' key '>)'], val);
        end
	end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            % Check operating system
			if ismac
				app.RunCommandEditField.Value = '/Applications/FlightGear';
			elseif ispc
				app.RunCommandEditField.Value = 'C:\Program Files\FlightGear';
			else
				app.RunCommandEditField.Value = 'LD_LIBRARY_PATH="" /usr/games/fgfs';
			end
			
			% Numeric drop-downs
			app.AngleUnitsDropDown.ItemsData = [1, pi/180]; % [rad] -> [rad], [deg] -> [rad]
			app.DistanceUnitsDropDown.ItemsData = [1, 1e3]; % [m] -> [m], [km] -> [m]
			
			% Date picker
			app.StartTimeUTCDatePicker.Value = today('datetime');
			
			% Configure internal timer to check connection
			app.isconnected = false;
			app.polling = timer('ExecutionMode', 'fixedSpacing', 'Period', app.wait, 'TimerFcn', @(~,~) app.checkStatus());
			
			% Init FlightGearAnimation
			app.animation = fganimation;
			app.animation.DestinationPort = num2str(app.playport);
			
			% Init vehicle properties
			app.vehicle = app.VehicleDropDown.Value;
			app.directory = '';
        end

        % Button pushed function: LoadCaseButton
        function LoadCaseButtonPushed(app, event)
			[file, path] = uigetfile({'*.mat', 'MAT-files (*.mat)'; '*.txt;*.dat;*.csv', 'Delimited text files (*.txt, *.dat, *.csv)'; '*.*', 'All files (*.*)'});
			if file
				filepath = fullfile(path, file);
				[~, ~, ext] = fileparts(filepath);
				try
					% Get matrix of data
					if strcmp(ext, '.mat')
						try
							data = struct2array(load(filepath));
						catch
							ME = MException('vist:load:mat', 'Could not open the MAT-file provided.');
							throw(ME);
						end
					else
						try
							data = readmatrix(filepath, 'FileType', 'text', 'ExpectedNumVariables', 7, 'OutputType', 'double');
						catch
							ME = MException('vist:load:text', 'Could not open the delimited text file provided.');
							throw(ME);
						end
					end
					
					% Check dimensions
					if ~any(size(data) == 7)
						ME = MException('vist:load:size', 'The file should contain 7 columns of data (time, latitude, longitude, altitude, phi, theta, psi).');
						throw(ME);
					end
					
					% Transpose if necessary
					if size(data, 2) ~= 7
						data = data.';
					end
					
					% Load data into table
					app.UITable.Data = data;
				catch ME
					uialert(app.VISTUIFigure, ['An error occured while trying to load the data from the selcted file. ' ME.message], 'Failed to load data');
				end
			end
        end

        % Menu selected function: EditMenu
        function EditMenuSelected(app, event)
            app.RunCommandEditField.Editable = ~app.RunCommandEditField.Editable;
			if app.RunCommandEditField.Editable
				app.EditMenu.Text = 'Done';
			else
				app.EditMenu.Text = 'Edit...';
			end
        end

        % Button pushed function: LaunchFlightGearButton
        function LaunchFlightGearButtonPushed(app, event)
			% Try to start FlightGear
            system([app.RunCommandEditField.Value app.getOpts() ' &']);
			
			% Wait...
			uiprogressdlg(app.VISTUIFigure, 'Title', 'Starting FlightGear...', 'Indeterminate', 'on');
			pause(app.wait);
			
			% ... and check what happened
			try
				app.T = py.FlightGear.Interface('localhost', app.dataport);
				app.polling.start();
			catch
				uialert(app.VISTUIFigure, 'An error occured while trying to start FlightGear. Please check that the Run Command is correct for your operating system and FlightGear installation path.', 'Failed to start FlightGear');
			end
        end

        % Close request function: VISTUIFigure
        function VISTUIFigureCloseRequest(app, event)
			% Stop internal timer
			app.polling.stop();
			app.polling.delete();
			
			% Destroy app
            delete(app);
        end

        % Menu selected function: ClearDataMenu
        function ClearDataMenuSelected(app, event)
            app.UITable.Data = [];
        end

        % Menu selected function: AddRowMenu
        function AddRowMenuSelected(app, event)
            app.UITable.Data = [app.UITable.Data; zeros(1,7)];
        end

        % Button pushed function: PlayButton
        function PlayButtonPushed(app, event)
			% Adjust table data with correct units
			data = app.UITable.Data;
			data(:,app.anglcol) = data(:,app.anglcol) * app.AngleUnitsDropDown.Value;
			data(:,app.distcol) = data(:,app.distcol) * app.DistanceUnitsDropDown.Value;
			
			% Update FlightGearAnimation properties
			app.animation.TimeSeriesSource = data;
			app.animation.TimeScaling = app.PlaybackSpeedSlider.Value;
			app.animation.FramesPerSecond = app.TargetFPSSlider.Value;
			app.animation.TStart = data(1,1);
			app.animation.TFinal = data(end,1);
			
			% Playback
			play(app.animation);
			
			% Disable 'Play' button until visualization is finished
			app.togglePlayStatus();
			playtimer = timer('StartDelay', data(end,1) / app.animation.TimeScaling, 'TimerFcn', @(~,~) app.togglePlayStatus());
			playtimer.start();
        end

        % Value changed function: AngleUnitsDropDown
        function AngleUnitsDropDownValueChanged(app, event)
			oldunits = app.AngleUnitsDropDown.Items{event.PreviousValue == app.AngleUnitsDropDown.ItemsData};
			newunits = app.AngleUnitsDropDown.Items{event.Value == app.AngleUnitsDropDown.ItemsData};
            app.UITable.ColumnName(app.anglcol) = strrep(app.UITable.ColumnName(app.anglcol), oldunits, newunits);
        end

        % Value changed function: DistanceUnitsDropDown
        function DistanceUnitsDropDownValueChanged(app, event)
            oldunits = app.DistanceUnitsDropDown.Items{event.PreviousValue == app.DistanceUnitsDropDown.ItemsData};
			newunits = app.DistanceUnitsDropDown.Items{event.Value == app.DistanceUnitsDropDown.ItemsData};
            app.UITable.ColumnName(app.distcol) = strrep(app.UITable.ColumnName(app.distcol), oldunits, newunits);
        end

        % Value changed function: ViewDropDown
        function ViewDropDownValueChanged(app, event)
			if app.isconnected
				app.T.set('/sim/current-view/view-number', app.ViewDropDown.Value);
			end
        end

        % Value changed function: StartTimeUTCDatePicker, 
        % TimeOfDayDropDown
        function TimeOfDayDropDownValueChanged(app, event)
			if app.isconnected
				app.T.set('/sim/time/gmt', [datestr(app.StartTimeUTCDatePicker.Value, 'yyyy-mm-dd') 'T' app.TimeOfDayDropDown.Value]);
			end
        end

        % Value changed function: WeatherDropDown
        function WeatherDropDownValueChanged(app, event)
			if app.isconnected
				app.T.set('/environment/weather-scenario', app.WeatherDropDown.Value);
			end
        end

        % Value changed function: EarthViewCheckBox
        function EarthViewCheckBoxValueChanged(app, event)
			if app.isconnected
				% Get strings
				if app.EarthViewCheckBox.Value
					valnum = '1';
					valstr = 'true';
				else
					valnum = '0';
					valstr = 'false';
				end
				
				% Set properties
				app.T.set('/earthview/control_loop_flag', valnum);
				app.T.set('/earthview/show-force-all', valnum);
				app.T.set('/earthview/show-n1', valstr);
				app.T.set('/earthview/show-n2', valstr);
				app.T.set('/earthview/show-n3', valstr);
				app.T.set('/earthview/show-n4', valstr);
				app.T.set('/earthview/show-s1', valstr);
				app.T.set('/earthview/show-s2', valstr);
				app.T.set('/earthview/show-s3', valstr);
				app.T.set('/earthview/show-s4', valstr);
			end
        end

        % Value changed function: VehicleDropDown
        function VehicleDropDownValueChanged(app, event)
			value = app.VehicleDropDown.Value;
			if strcmp(value, 'custom')
				[file, path] = uigetfile({'*.xml', 'FlightGear Model (*-set.xml)'; '*.ac', 'AC3D CAD Geometry (*.ac)'});
				if file
					filepath = fullfile(path, file);
					[~, name, ext] = fileparts(filepath);
					try
						if strcmp(ext, '.xml')
							try
								app.vehicle = erase(name, '-set');
								app.directory = path;
							catch
								ME = MException('vist:vehicle:xml', 'Provided file is not formatted as <custom>-set.xml.');
								throw(ME);
							end
						elseif strcmp(ext, '.ac')
							try
								app.vehicle = name;
								app.directory = tempname;
								
								% Generate directory structure
								mkdir(app.directory);
								mkdir(app.directory, 'Models');
								
								% Generate <custom>.xml
								content = fileread(fullfile(fileparts(mfilename('fullpath')), 'examples', 'apollo', 'Models', 'apollo.xml'));
								content = app.xmlrep(content, 'path', filepath);
								xml = fopen(fullfile(app.directory, 'Models', [name '.xml']), 'w');
								fwrite(xml, content);
								fclose(xml);
								
								% Generate <custom>-set.xml
								content = fileread(fullfile(fileparts(mfilename('fullpath')), 'examples', 'apollo', 'apollo-set.xml'));
								content = app.xmlrep(content, 'description', upper(name));
								content = app.xmlrep(content, 'path', fullfile('Models', [name '.xml']));
								xml = fopen(fullfile(app.directory, [name '-set.xml']), 'w');
								fwrite(xml, content);
								fclose(xml);
							catch
								ME = MException('vist:vehicle:temp', 'Could not generate temporary directory or files.');
								throw(ME);
							end
						else
							ME = MException('vist:vehicle:format', 'Unexpected file format provided.');
							throw(ME);
						end
					catch ME
						uialert(app.VISTUIFigure, ['An error occured while trying to load the selected vehicle. ' ME.message], 'Failed to load vehicle');
					end
				end
				app.Label.Text = app.vehicle;
				app.Label.Visible = true;
			else
				app.vehicle = value;
				app.Label.Visible = false;
			end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.VISTUIFigure = uifigure('Visible', 'off');
            app.VISTUIFigure.Name = 'VIST';
            app.VISTUIFigure.CloseRequestFcn = createCallbackFcn(app, @VISTUIFigureCloseRequest, true);
            app.GridLayout = uigridlayout(app.VISTUIFigure);
            app.OptionsPanel = uipanel(app.GridLayout);
            app.OptionsPanel.Title = 'Options';
            app.GridLayout7 = uigridlayout(app.OptionsPanel);
            app.GridLayout8 = uigridlayout(app.GridLayout7);
            app.LaunchFlightGearButton = uibutton(app.GridLayout8, 'push');
            app.LaunchFlightGearButton.ButtonPushedFcn = createCallbackFcn(app, @LaunchFlightGearButtonPushed, true);
            app.LaunchFlightGearButton.Text = 'Launch FlightGear';
            app.GridLayout9 = uigridlayout(app.GridLayout8);
            app.ConnectionLampLabel = uilabel(app.GridLayout9);
            app.ConnectionLampLabel.Text = 'Disconnected';
            app.ConnectionLamp = uilamp(app.GridLayout9);
            app.GridLayout10 = uigridlayout(app.GridLayout7);
            app.VehicleDropDownLabel = uilabel(app.GridLayout10);
            app.VehicleDropDownLabel.Text = 'Vehicle';
            app.VehicleDropDown = uidropdown(app.GridLayout10);
            app.VehicleDropDown.Items = {'HL20', 'Custom...'};
            app.VehicleDropDown.ItemsData = {'HL20', 'custom'};
            app.VehicleDropDown.ValueChangedFcn = createCallbackFcn(app, @VehicleDropDownValueChanged, true);
            app.VehicleDropDown.Value = 'HL20';
            app.ViewDropDownLabel = uilabel(app.GridLayout10);
            app.ViewDropDownLabel.Text = 'View';
            app.ViewDropDown = uidropdown(app.GridLayout10);
            app.ViewDropDown.Items = {'Cockpit', 'Helicopter', 'Chase', 'Chase Without Yaw', 'Fly-By'};
            app.ViewDropDown.ItemsData = {'0', '1', '2', '5', '6'};
            app.ViewDropDown.ValueChangedFcn = createCallbackFcn(app, @ViewDropDownValueChanged, true);
            app.ViewDropDown.Value = '1';
            app.WeatherDropDownLabel = uilabel(app.GridLayout10);
            app.WeatherDropDownLabel.Text = 'Weather';
            app.WeatherDropDown = uidropdown(app.GridLayout10);
            app.WeatherDropDown.Items = {'Fair', 'Warm', 'Thunderstorm', 'Fog'};
            app.WeatherDropDown.ItemsData = {'Fair weather', 'Warm sector', 'Thunderstorm', 'Early morning fog'};
            app.WeatherDropDown.ValueChangedFcn = createCallbackFcn(app, @WeatherDropDownValueChanged, true);
            app.WeatherDropDown.Value = 'Fair weather';
            app.GridLayout13 = uigridlayout(app.GridLayout10);
            app.EarthViewCheckBox = uicheckbox(app.GridLayout13);
            app.EarthViewCheckBox.ValueChangedFcn = createCallbackFcn(app, @EarthViewCheckBoxValueChanged, true);
            app.EarthViewCheckBox.Text = 'EarthView';
            app.TerraSyncCheckBox = uicheckbox(app.GridLayout13);
            app.TerraSyncCheckBox.Text = 'TerraSync';
            app.StartTimeUTCDatePickerLabel = uilabel(app.GridLayout10);
            app.StartTimeUTCDatePickerLabel.Text = 'Start Time UTC';
            app.StartTimeUTCDatePicker = uidatepicker(app.GridLayout10);
            app.StartTimeUTCDatePicker.DisplayFormat = 'uuuu/MM/dd';
            app.StartTimeUTCDatePicker.ValueChangedFcn = createCallbackFcn(app, @TimeOfDayDropDownValueChanged, true);
            app.TimeOfDayDropDown = uidropdown(app.GridLayout10);
            app.TimeOfDayDropDown.Items = {'Dawn', 'Morning', 'Noon', 'Afternoon', 'Dusk', 'Evening', 'Midnight'};
            app.TimeOfDayDropDown.ItemsData = {'06:00:00', '09:00:00', '12:00:00', '15:00:00', '18:00:00', '21:00:00', '00:00:00'};
            app.TimeOfDayDropDown.ValueChangedFcn = createCallbackFcn(app, @TimeOfDayDropDownValueChanged, true);
            app.TimeOfDayDropDown.Value = '09:00:00';
            app.Label = uilabel(app.GridLayout10);
            app.Label.Visible = 'off';
            app.GridLayout11 = uigridlayout(app.GridLayout7);
            app.RunCommandEditFieldLabel = uilabel(app.GridLayout11);
            app.RunCommandEditFieldLabel.Text = 'Run Command';
            app.RunCommandEditField = uieditfield(app.GridLayout11, 'text');
            app.RunCommandEditField.Editable = 'off';
            app.GridLayout12 = uigridlayout(app.GridLayout7);
            app.PlaybackSpeedSliderLabel = uilabel(app.GridLayout12);
            app.PlaybackSpeedSliderLabel.Text = 'Playback Speed';
            app.PlaybackSpeedSlider = uislider(app.GridLayout12);
            app.PlaybackSpeedSlider.Limits = [1 100];
            app.PlaybackSpeedSlider.MajorTicks = [1 20 40 60 80 100];
            app.PlaybackSpeedSlider.MajorTickLabels = {'1', '20', '40', '60', '80', '100'};
            app.PlaybackSpeedSlider.MinorTicks = [10 30 50 70 90];
            app.PlaybackSpeedSlider.Value = 20;
            app.TargetFPSSliderLabel = uilabel(app.GridLayout12);
            app.TargetFPSSliderLabel.Text = 'Target FPS';
            app.TargetFPSSlider = uislider(app.GridLayout12);
            app.TargetFPSSlider.Limits = [15 120];
            app.TargetFPSSlider.MajorTicks = [15 30 60 90 120];
            app.TargetFPSSlider.MinorTicks = [24 25 48];
            app.TargetFPSSlider.Value = 15;
            app.SimulationPanel = uipanel(app.GridLayout);
            app.SimulationPanel.Title = 'Simulation';
            app.GridLayout2 = uigridlayout(app.SimulationPanel);
            app.UITable = uitable(app.GridLayout2);
            app.UITable.ColumnName = {'Time [s]'; 'Latitude [rad]'; 'Longitude [rad]'; 'Altitude [m]'; 'Phi [rad]'; 'Theta [rad]'; 'Psi [rad]'};
            app.UITable.RowName = {};
            app.UITable.ColumnEditable = true;
            app.GridLayout4 = uigridlayout(app.GridLayout2);
            app.LoadCaseButton = uibutton(app.GridLayout4, 'push');
            app.LoadCaseButton.ButtonPushedFcn = createCallbackFcn(app, @LoadCaseButtonPushed, true);
            app.LoadCaseButton.Text = 'Load Case';
            app.PlayButton = uibutton(app.GridLayout4, 'push');
            app.PlayButton.ButtonPushedFcn = createCallbackFcn(app, @PlayButtonPushed, true);
            app.PlayButton.Text = 'Play';
            app.GridLayout5 = uigridlayout(app.GridLayout4);
            app.AngleUnitsDropDownLabel = uilabel(app.GridLayout5);
            app.AngleUnitsDropDownLabel.Text = 'Angle Units';
            app.AngleUnitsDropDown = uidropdown(app.GridLayout5);
            app.AngleUnitsDropDown.Items = {'[rad]', '[deg]'};
            app.AngleUnitsDropDown.ValueChangedFcn = createCallbackFcn(app, @AngleUnitsDropDownValueChanged, true);
            app.AngleUnitsDropDown.Value = '[rad]';
            app.GridLayout6 = uigridlayout(app.GridLayout4);
            app.DistanceUnitsDropDownLabel = uilabel(app.GridLayout6);
            app.DistanceUnitsDropDownLabel.Text = 'Distance Units';
            app.DistanceUnitsDropDown = uidropdown(app.GridLayout6);
            app.DistanceUnitsDropDown.Items = {'[m]', '[km]'};
            app.DistanceUnitsDropDown.ValueChangedFcn = createCallbackFcn(app, @DistanceUnitsDropDownValueChanged, true);
            app.DistanceUnitsDropDown.Value = '[m]';
            app.ContextMenu = uicontextmenu(app.VISTUIFigure);
            % Assign app.ContextMenu
            app.UITable.ContextMenu = app.ContextMenu;
            app.AddRowMenu = uimenu(app.ContextMenu);
            app.AddRowMenu.MenuSelectedFcn = createCallbackFcn(app, @AddRowMenuSelected, true);
            app.AddRowMenu.Text = 'Add Row';
            app.ClearDataMenu = uimenu(app.ContextMenu);
            app.ClearDataMenu.MenuSelectedFcn = createCallbackFcn(app, @ClearDataMenuSelected, true);
            app.ClearDataMenu.Text = 'Clear Data';
            app.ContextMenu2 = uicontextmenu(app.VISTUIFigure);
            % Assign app.ContextMenu2
            app.RunCommandEditField.ContextMenu = app.ContextMenu2;
            app.EditMenu = uimenu(app.ContextMenu2);
            app.EditMenu.MenuSelectedFcn = createCallbackFcn(app, @EditMenuSelected, true);
            app.EditMenu.Text = 'Edit...';
            % Show the figure after all components are created
            app.VISTUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = vist

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.VISTUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.VISTUIFigure)
        end
    end
end